classdef tugas4_13519170 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label2             matlab.ui.control.Label
        Label              matlab.ui.control.Label
        ResultCNNLabel     matlab.ui.control.Label
        ResultSVMLabel     matlab.ui.control.Label
        ChooseImageButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage % gambar yang ingin diklasifikasikan
    end
    
    methods (Access = private)        
        function main(app)
            cla(app.UIAxes);
            imshow(app.OriginalImage, 'Parent', app.UIAxes);
            svm_result = runSVMclassifier(app);
            app.Label.Text = svm_result(1);
            %set(app.Label, "String", string(svm_result));
            [label, probability] = runCNNclassifier(app);
            %set(app.Label2, 'String', [string(label), char(probability)]);
            app.Label2.Text = [label(1), char(probability(1))];
        end
        function result = runSVMclassifier(app)
            load SVM_classifier.mat
            scaledImage = imresize(app.OriginalImage, [256, 256]);
            featureTest = extractHOGFeatures(scaledImage);
            [predictIndex, score] = predict(SVM_classifier, featureTest);
            result = predictIndex;
        end
        function [label, probability] = runCNNclassifier(app)
            load net.mat
            scaledImage = imresize(app.OriginalImage, [224, 224] );
            [label, probability] = classify(net, scaledImage);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [name, extention] = uigetfile({'*.jpg;*.tif;*.png;*.gif; *.jpeg'}, 'Pick an image');
            if ischar(name)
                filename = [extention, name];
                loadedImage = imread(filename);
                app.OriginalImage = loadedImage;
                main(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.ResultSVMLabel = uilabel(app.UIFigure);
            app.ResultSVMLabel.Text = 'Result SVM';
            app.ResultCNNLabel = uilabel(app.UIFigure);
            app.ResultCNNLabel.Text = 'Result CNN';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tugas4_13519170

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end